classdef m4 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure            matlab.ui.Figure
    Toolbar             matlab.ui.container.Toolbar
    SelectFolderButton  matlab.ui.container.toolbar.PushTool
    VisualizationLabel  matlab.ui.control.Label
    ExporttoCSVButton   matlab.ui.control.Button
    MetadataLabel       matlab.ui.control.Label
    DataLabel           matlab.ui.control.Label
    MetadataTextArea    matlab.ui.control.TextArea
    UITable             matlab.ui.control.Table
    UIAxes              matlab.ui.control.UIAxes
  end

  
  properties (Access = private)
    folder_path % Folder where data is stored
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      app.MetadataTextArea.Editable="off";
      app.ExporttoCSVButton.Enable='off';
    end

    % Callback function: SelectFolderButton
    function SelectFolderButtonClicked(app, event)
      clc
      app.folder_path = uigetdir;
      if isequal(app.folder_path, 0)
        return; % User canceled
      end
      [myCSV,metadata] = m4toCSV(app.folder_path);
      %
      % Populate table and metadata text area
      %
      app.UITable.Data = myCSV;
      app.UITable.ColumnName = myCSV.Properties.VariableNames;
      app.MetadataTextArea.Value = ...
          [sprintf('Date:\t\t\t%s\n',...
            string(metadata.CollectionDate)),...
           sprintf('File name:\t\t%s\n',...
            string(metadata.FileName)),...
           sprintf('Electrolyte matrix:\t%s\n',...
            string(metadata.ElectrolyteMatrix)),...
           sprintf('Electrolyte volume:\t%s\n',...
            string(metadata.ElectrolyteVolume)),...
           sprintf('Control mode:\t\t%s\n',...
            string(metadata.ControlMode)),...
           sprintf('Aperture diameter:\t%s\n',...
            string(metadata.ApertureDiameter)),...
           sprintf('Aperture current:\t%s\n',...
            string(metadata.ApertureCurrent)),...
           sprintf('Elapsed time:\t\t%s\n',...
            string(metadata.ElapsedTime)),...
           sprintf('Num. size bins:\t%s\n',...
            string(metadata.NumSizeBins)),...
           sprintf('Total pulses:\t\t%s\n',...
            string(metadata.TotalPulses))];
      %
      % Produce visualization
      %
      raw_data = myCSV{:,2:end};
      xtl = myCSV.Properties.VariableNames;
      xtl = xtl(2:end); % Omit 'File'
      yl = myCSV.Properties.VariableUnits;
      yl = yl{2}; % Y-axis label
      x = 0:size(raw_data,2)-1;
      plot(app.UIAxes, x, raw_data, 'LineWidth', 2);
      num_colors = size(raw_data,1);
      C = cat_colors('batlowK',num_colors);
      colororder(app.UIAxes,C);
      ylabel(app.UIAxes,yl)
      xticks(app.UIAxes,x)
      xticklabels(app.UIAxes,xtl)
      set(app.UIAxes,'YScale','log')
      lg=legend(app.UIAxes,myCSV.File,...
        'Location','eastoutside',...
        'IconColumnWidth',14,...
        'Interpreter','none',...
        'NumColumns',2);
      lg.Title.String = app.folder_path;
      lg.FontName = 'Consolas';
      %
      % Turn on Save button
      %
      app.ExporttoCSVButton.Enable = 'on';
    end

    % Button pushed function: ExporttoCSVButton
    function ExporttoCSVButtonPushed(app, event)
      data = app.UITable.Data;
      [user_file, user_path] = uiputfile('*.csv', 'Save As:', [app.folder_path '.csv']);
      if isequal(user_file, 0) || isequal(user_path, 0)
        return; % User canceled
      end
      final_final_path = fullfile(user_path, user_file);
      writetable(data, final_final_path);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.Toolbar = uitoolbar(app.UIFigure);
      app.SelectFolderButton = uipushtool(app.Toolbar);
      app.SelectFolderButton.Tooltip = {'Select folder'};
      app.SelectFolderButton.ClickedCallback = createCallbackFcn(app, @SelectFolderButtonClicked, true);
      app.SelectFolderButton.Icon = 'lightblue.png';
      app.UIAxes = uiaxes(app.UIFigure);
      xlabel(app.UIAxes, 'Size Channel')
      ylabel(app.UIAxes, 'Counts / 50 uL')
      zlabel(app.UIAxes, 'Z')
      app.UITable = uitable(app.UIFigure);
      app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
      app.UITable.RowName = {};
      app.MetadataTextArea = uitextarea(app.UIFigure);
      app.DataLabel = uilabel(app.UIFigure);
      app.DataLabel.Text = 'Data';
      app.MetadataLabel = uilabel(app.UIFigure);
      app.MetadataLabel.Text = 'Metadata';
      app.ExporttoCSVButton = uibutton(app.UIFigure, 'push');
      app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
      app.ExporttoCSVButton.Text = 'Export to CSV';
      app.VisualizationLabel = uilabel(app.UIFigure);
      app.VisualizationLabel.Text = 'Visualization';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = m4

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end